classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        DPCMENCODERANDDECODERLabel    matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        AttachExcelFolderButton       matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField_3                   matlab.ui.control.NumericEditField
        SetStepSizeButton             matlab.ui.control.Button
        StepSizeSetLampLabel          matlab.ui.control.Label
        StepSizeSetLamp               matlab.ui.control.Lamp
        QuantizerModeDropDownLabel    matlab.ui.control.Label
        QuantizerModeDropDown         matlab.ui.control.DropDown
        PredictorOrderDropDownLabel   matlab.ui.control.Label
        PredictorOrderDropDown        matlab.ui.control.DropDown
        TextArea                      matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        PerformDPCMCaSwitch           matlab.ui.control.RockerSwitch
        UITable2                      matlab.ui.control.Table
        TableValuesLampLabel          matlab.ui.control.Label
        TableValuesLamp               matlab.ui.control.Lamp
        ClearTableButton              matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        CantseePresshereforplotsinaseparatewindowButton  matlab.ui.control.Button
        QuantizerModeDropDownLabel_2  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        EditField_5                   matlab.ui.control.NumericEditField
        QuantizerModeDropDownLabel_3  matlab.ui.control.Label
        QuantizerModeDropDownLabel_4  matlab.ui.control.Label
        EditField_6                   matlab.ui.control.NumericEditField
        QuantizerModeDropDownLabel_5  matlab.ui.control.Label
        EditField_7                   matlab.ui.control.NumericEditField
        QuantizerModeDropDownLabel_6  matlab.ui.control.Label
        EditField_8                   matlab.ui.control.NumericEditField
        AddCosineFunctionButton       matlab.ui.control.Button
        QuantizerModeDropDownLabel_7  matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        Label_4                       matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
    end

    
    properties (Access = public)
        TOO_COOL_FOR_A_TITLE  matlab.ui.Figure
    end
    
    properties (Access = private)
        t; % contains all my table data
        color1; %% a variable to tell me that the step lamp is now green
        color2; %% a variable to tell me that the table has values
        mat; %% saving the table data in matrix form
        i = 0; %% flag to show if the table already contains data from excel
        j = 0; % flag to show if the table already contains entered data through the fields
        step = 0; %% the step size
        time; %% the time vector
        m; %% this is the input signal
        d; %% the difference signal
        dq; %% the quantized difference signal
        mq; %% quantized signal amplitudes
        mq_roof; %% ^m[k]
        error; %% the square quantization error
        sum = 0; %% sum of quantization errors
        count = 0; %% counting the number of samples
        bigger_plots; %%a signal to tell me that the user wants to graph on separate pages for more visibility
        flag_cosine = false; %% if this flag is raised, the cosine function was used and if the user opted for an excel folder or a button add the app resets
        flag_cosine2 = 1; %% another flag to show that cosine was used 
        message_freq;
        sampling_freq;
        Levels;
        max_amp;
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AttachExcelFolderButton
        function AttachExcelFolderButtonPushed(app, event)
       if (app.flag_cosine && app.flag_cosine2 == 0)
                 %% the user has to press "clear table" if they want to use the other methods after using cosine
                app.flag_cosine2 = 1;
                   app.time=[];
            app.m = [];
            app.d = [];
            app.dq = [];
            app.mq = [];
            app.mq_roof = [];
                 return
       end
            try %% exception handling
            [filename, pathname]=uigetfile({'*.xlsx'},'File Selector'); %% reading excel folder (i.e. only .xlsx)
            app.t = app.UITable.Data; 
 text = readtable([pathname filename], "Sheet",1, 'ReadVariableNames',false);
 text =[text(:,1) text(:,2)];
       fl = cellfun(@isnumeric,table2cell(text(1,:))); 
if (numel(fl(fl == false)) > 0) 
    flag = false;
else
    flag = true;
end
if (flag)       
    app.i = 1;
            if (app.j ==1 || app.flag_cosine)
        app.flag_cosine=0;
        text.Properties.VariableNames = app.t.Properties.VariableNames; %% ensuring that, if there was, existing data it will not be overwritten
            end
            A = [app.t; text];
            app.UITable.Data = A;
            app.TableValuesLamp.Color = 'g';
            app.color2 = 1;
            app.UITable.Data = fillmissing(app.UITable.Data, 'constant', 0); %% replacing all nan values by zero
            app.t = app.UITable.Data; 
            app.mat = app.t; 
            app.mat{:,:}; %% putting table data in matrix form
end
       catch 
           return % the purpose of exception handling is to allow the user to close the attachment page without an error lest they change their mind and have to reboot the whole GUI - just providing a smoother experience.
        end
    

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
           if (app.flag_cosine && app.flag_cosine2 == 0)
                 %% the user has to press "clear table" if they want to use the other methods after using cosine
                app.flag_cosine2 = 1;
                   app.time=[];
            app.m = [];
            app.d = [];
            app.dq = [];
            app.mq = [];
            app.mq_roof = [];
                 return
           end
            app.t = app.UITable.Data;
            x = app.EditField.Value;
            y = app.EditField_2.Value;
            app.j = 1;
            if(app.i ==1 || app.flag_cosine == true)
            nr = table(x, y, 'VariableNames', app.t.Properties.VariableNames);
           app.flag_cosine=0;
            else
            nr = table(x, y);
            end
            app.UITable.Data = [app.t; nr];
            app.TableValuesLamp.Color = 'g';
            app.color2 = 1;
            app.t = app.UITable.Data; 
            app.mat = app.t; 
            app.mat{:,:};
            P = str2double('0'); %% reseting the field to 0
            app.EditField.Value = P; %% reseting the field to 0
            app.EditField_2.Value = P; %% reseting the field to 0

        end

        % Button pushed function: SetStepSizeButton
        function SetStepSizeButtonPushed(app, event)

           if (app.EditField_3.Value > 0)
           app.step=app.EditField_3.Value;
           app.StepSizeSetLamp.Color = 'g';
           app.color1 = 1;
           k = num2str(app.step);
           app.TextArea.Value = k;         
         else 
            P = num2str(app.step);
            L = str2double(P);
            app.EditField_3.Value = L;
          end
          
        end

        % Value changed function: PerformDPCMCaSwitch
        function PerformDPCMCaSwitchValueChanged(app, event)
            value = app.PerformDPCMCaSwitch.Value;
            app.PerformDPCMCaSwitch.Value = 'Off';
            try 
            if (app.color1 == 1 && app.color2 == 1)
                app.UITable2.Data(:,:) = [];
                app.sum = 0;
                tb = app.UITable2.Data;
            matrix = transpose(app.mat{:,:});
            if (app.QuantizerModeDropDown.Value == "Mid-tread")
                x = 1/app.step; %% getting the rounding factor
            else
                x = (app.step/2); %% getting the rounding factor
            end
            ncols = size(matrix,2); %% getting the number of amplitudes available             
            if (app.PredictorOrderDropDown.Value == "First-order predictor")
               for k =1:ncols
                   app.m(k) = matrix(2,k);
                   app.time(k)=matrix(1,k);
             if (k==1)
                    app.d(k) = matrix(2,k);
                    app.mq_roof(k) = 0;
             else 
                   app.mq_roof(k) = app.mq(k-1);
                   app.d(k)=matrix(2,k) - app.mq_roof(k);
             end
                if (app.QuantizerModeDropDown.Value == "Mid-tread")
             app.dq(k) = round(app.d(k)*x)/x;
                else
              app.dq(k) = round((app.d(k)/(2*x)-.5))*(2*x)+x;
                end
             app.mq(k) = app.mq_roof(k) + app.dq(k);
             app.error(k) = (app.m(k) - app.mq(k))^2; 
             app.sum=app.sum + app.error(k); %% sum is intiated to zero and is used to calculate the sum of the errors
             new_data = table(app.time(k), app.d(k),app.dq(k),app.mq(k),app.mq_roof(k), app.error(k));
               app.UITable2.Data = [tb; new_data];
               tb = app.UITable2.Data;
               end
            else 
                for k =1:ncols
                      app.m(k) = matrix(2,k);
                   app.time(k)=matrix(1,k);
             if (k==1)
                    app.d(k) = matrix(2,k);
                    app.mq_roof(k) = 0;
             elseif (k==2)
                    app.mq_roof(k) = 1/2*app.mq(k-1);
                    app.d(k)=matrix(2,k) - app.mq_roof(k);
             else
                   app.mq_roof(k) = 1/2*(app.mq(k-1)+app.mq(k-2));
                   app.d(k)=matrix(2,k) - app.mq_roof(k);
             end
             app.dq(k) = round(app.d(k)*x)/x;
             app.mq(k) = app.mq_roof(k) + app.dq(k);
             app.error(k) = (app.m(k) - app.mq(k))^2; 
             app.sum=app.sum + app.error(k);
             new_data = table(app.time(k), app.d(k),app.dq(k),app.mq(k),app.mq_roof(k), app.error(k));
               app.UITable2.Data = [tb; new_data];
               tb = app.UITable2.Data;
               end
            end
            str = num2str(app.sum/ncols);
            app.TextArea_2.Value = str; %% text areas cant show int types
            if (app.flag_cosine)
               app.Panel_2.AutoResizeChildren = 'off';
                ax1 = subplot(4,1,1,'Parent',app.Panel_2); %% to show the original messsage 
                ax2 = subplot(4,1,2,'Parent',app.Panel_2); %% to show the reconstructed message 
                ax3 = subplot(4,1,3,'Parent',app.Panel_2); %% to show m[k] v mq[k]
                ax4 = subplot(4,1,4,'Parent',app.Panel_2); %% to show d[k] v dq[k]
                syms x
                y = app.max_amp*cos(2*pi*app.message_freq*x);
                fplot(ax1, y, [0,1/app.message_freq]);
                title(ax1,'The Original Message');
                stem(ax2, app.time, app.m)
                hold(ax2,'on');
                plot(ax2,app.time,app.m);
                title(ax2,'The Reconstructed Message')
                hold(ax2,'off');
                plot(ax3,app.time,app.m)
                hold(ax3, 'on' );
                stairs(ax3, app.time, app.mq)
                legend(ax3,'m[k]','m_q[k]');
                title(ax3, 'm[k] vs m_q[k]');
                hold(ax3, 'off' );
                stem(ax4,app.time, app.d,'LineWidth',2)
                hold(ax4, 'on' );
                stem(ax4,app.time,app.dq, 'LineWidth',2)
                legend(ax4,'d[k]','d_q[k]');
                title(ax4,'d[k] vs d_q[k]') 
                hold(ax4, 'off' );
                app.bigger_plots = 1; %% the user cant opt for separate pages if he has not used this function first as it has all the calculations.
            else
                app.Panel_2.AutoResizeChildren = 'off';
                ax1 = subplot(2,1,1,'Parent',app.Panel_2);
                ax2 = subplot(2,1,2,'Parent',app.Panel_2);
                plot(ax1,app.time,app.m)
                hold(ax1, 'on' );
                stairs(ax1, app.time, app.mq)
                legend(ax1,'m[k]','m_q[k]');
                title(ax1, 'm[k] vs m_q[k]');
                hold(ax1, 'off' );
                stem(ax2,app.time, app.d,'LineWidth',2)
                hold(ax2, 'on' );
                stem(ax2,app.time,app.dq, 'LineWidth',2)
                legend(ax2,'d[k]','d_q[k]');
                title(ax2,'d[k] vs d_q[k]')
                hold(ax2, 'off' );
                app.bigger_plots = 1; %% the user cant opt for separate pages if he has not used this function first as it has all the calculations.
            end
         
            end
            catch
                    return
            end


        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            app.UITable.Data(:,:) = [];
            clearvars -except app.flag_cosine;
        end

        % Button pushed function: 
        % CantseePresshereforplotsinaseparatewindowButton
        function CantseePresshereforplotsinaseparatewindowButtonPushed(app, event)
            if (app.bigger_plots == 1 && app.flag_cosine)
                subplot(4,1,1)
                syms x
                y = app.max_amp*cos(2*pi*app.message_freq*x);
                fplot( y, [0,1/app.message_freq]);
                title('The Original Message');
                subplot(4,1,2)
                stem(app.time, app.m);
                hold('on');
                plot(app.time,app.m);
                title('The Reconstructed Message');
                hold('off');
                subplot(4,1,3)
                plot(app.time,app.m);
                hold('on' );
                stairs(app.time, app.mq);
                legend('m[k]','m_q[k]');
                title('m[k] vs m_q[k]');
                hold('off');
                subplot(4,1,4)
                stem(app.time, app.d,'LineWidth',2)
                hold('on' );
                stem(app.time,app.dq, 'LineWidth',2)
                legend('d[k]','d_q[k]');
                title('d[k] vs d_q[k]');
                hold('off');
            elseif (app.bigger_plots == 1)
        subplot(2,1,1)
        plot(app.time,app.m);
        hold('on' );
        stairs(app.time, app.mq_roof)
        legend('m[k]','m_q[k]');
        title('m[k] vs m_q[k]');
        hold('off');
        subplot(2,1,2)
        stem(app.time, app.d,'LineWidth',2)
        hold('on' );
       stem(app.time,app.dq, 'LineWidth',2)
       legend('d[k]','d_q[k]');
       title('d[k] vs d_q[k]');
       hold('off');
            end
        end

        % Button pushed function: AddCosineFunctionButton
        function AddCosineFunctionButtonPushed(app, event)
              
                app.message_freq = app.EditField_5.Value;
                app.sampling_freq = app.EditField_6.Value;
                app.Levels = app.EditField_7.Value;
                app.max_amp = app.EditField_8.Value;
            if (app.message_freq ~= 0 && app.sampling_freq ~= 0 && app.Levels ~= 0 && app.max_amp ~= 0)
           app.time=[];
            app.m = [];
            app.d = [];
            app.dq = [];
            app.mq = [];
            app.mq_roof = [];
                app.UITable.Data(:,:) = []; 
                tb = app.UITable.Data;
                app.StepSizeSetLamp.Color = 'g';
                app.TableValuesLamp.Color = 'g';
                app.color1=1;
                app.color2 = 1;
                app.message_freq = app.EditField_5.Value;
                app.sampling_freq = app.EditField_6.Value;
                app.Levels = app.EditField_7.Value;
                app.max_amp = app.EditField_8.Value;
                app.step = (2*app.max_amp)/app.Levels;
                str = num2str(app.step);
                app.TextArea.Value = str; 
                period = 1/app.message_freq; %% calculating the period 
                t_s = 1/app.sampling_freq; %% calculating the sampling period 
                for k = 0: t_s: period
                    new_data = app.max_amp*cos(2*pi*app.message_freq*k);
                    if (app.i == 1 || app.j == 1)
                    table_data = table(k, new_data, 'VariableNames', app.t.Properties.VariableNames);
                    else
                    table_data = table(k, new_data);
                    end
                    app.UITable.Data = [tb; table_data];
                    tb = app.UITable.Data;
                end
                app.mat = app.UITable.Data;
                app.flag_cosine = true;
                app.flag_cosine2 = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.DPCMENCODERANDDECODERLabel = uilabel(app.Panel);
            app.DPCMENCODERANDDECODERLabel.Text = {'DPCM ENCODER AND DECODER'; ''};
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Time Vector'; 'Amplitude Vector'; ''; ''};
            app.UITable.RowName = {};
            app.AttachExcelFolderButton = uibutton(app.Panel, 'push');
            app.AttachExcelFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AttachExcelFolderButtonPushed, true);
            app.AttachExcelFolderButton.Text = 'Attach Excel Folder';
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add ';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.SetStepSizeButton = uibutton(app.Panel, 'push');
            app.SetStepSizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetStepSizeButtonPushed, true);
            app.SetStepSizeButton.Text = 'Set Step Size';
            app.StepSizeSetLampLabel = uilabel(app.Panel);
            app.StepSizeSetLampLabel.Text = 'Step Size Set';
            app.StepSizeSetLamp = uilamp(app.Panel);
            app.QuantizerModeDropDownLabel = uilabel(app.Panel);
            app.QuantizerModeDropDownLabel.Text = 'Quantizer Mode';
            app.QuantizerModeDropDown = uidropdown(app.Panel);
            app.QuantizerModeDropDown.Items = {'Mid-rise', 'Mid-tread'};
            app.QuantizerModeDropDown.Value = 'Mid-rise';
            app.PredictorOrderDropDownLabel = uilabel(app.Panel);
            app.PredictorOrderDropDownLabel.Text = 'Predictor Order';
            app.PredictorOrderDropDown = uidropdown(app.Panel);
            app.PredictorOrderDropDown.Items = {'First-order predictor', 'Second-order averaging predictor'};
            app.PredictorOrderDropDown.Value = 'First-order predictor';
            app.TextArea = uitextarea(app.Panel);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = '4753196.png';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '=';
            app.PerformDPCMCaSwitch = uiswitch(app.Panel, 'rocker');
            app.PerformDPCMCaSwitch.Items = {'Off', 'Perform DPCM Calculations'};
            app.PerformDPCMCaSwitch.ValueChangedFcn = createCallbackFcn(app, @PerformDPCMCaSwitchValueChanged, true);
            app.UITable2 = uitable(app.Panel);
            app.UITable2.ColumnName = {'time'; 'd[k]'; 'dq[k]'; 'mq[k]'; 'm[k]_bar'; 'quantization error'};
            app.UITable2.RowName = {};
            app.TableValuesLampLabel = uilabel(app.Panel);
            app.TableValuesLampLabel.Text = 'Table Values';
            app.TableValuesLamp = uilamp(app.Panel);
            app.ClearTableButton = uibutton(app.Panel, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.Panel_2 = uipanel(app.Panel);
            app.CantseePresshereforplotsinaseparatewindowButton = uibutton(app.Panel_2, 'push');
            app.CantseePresshereforplotsinaseparatewindowButton.ButtonPushedFcn = createCallbackFcn(app, @CantseePresshereforplotsinaseparatewindowButtonPushed, true);
            app.CantseePresshereforplotsinaseparatewindowButton.Text = 'Can''t see? Press here for plots in a separate window';
            app.QuantizerModeDropDownLabel_2 = uilabel(app.Panel);
            app.QuantizerModeDropDownLabel_2.Text = 'And/Or';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.QuantizerModeDropDownLabel_3 = uilabel(app.Panel);
            app.QuantizerModeDropDownLabel_3.Text = 'Message Frequency';
            app.QuantizerModeDropDownLabel_4 = uilabel(app.Panel);
            app.QuantizerModeDropDownLabel_4.Text = 'Sampling Frequency';
            app.EditField_6 = uieditfield(app.Panel, 'numeric');
            app.QuantizerModeDropDownLabel_5 = uilabel(app.Panel);
            app.QuantizerModeDropDownLabel_5.Text = 'Levels';
            app.EditField_7 = uieditfield(app.Panel, 'numeric');
            app.QuantizerModeDropDownLabel_6 = uilabel(app.Panel);
            app.QuantizerModeDropDownLabel_6.Text = 'Maximum Amplitude';
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.AddCosineFunctionButton = uibutton(app.Panel, 'push');
            app.AddCosineFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @AddCosineFunctionButtonPushed, true);
            app.AddCosineFunctionButton.Text = 'Add Cosine Function';
            app.QuantizerModeDropDownLabel_7 = uilabel(app.Panel);
            app.QuantizerModeDropDownLabel_7.Text = 'Or';
            app.TextArea_2 = uitextarea(app.Panel);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '=';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = 'epsilon.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end